classdef group_2_stress_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Theta_S_2              matlab.ui.control.NumericEditField
        EditField2_3Label_11   matlab.ui.control.Label
        Theta_S                matlab.ui.control.NumericEditField
        EditField2_3Label_10   matlab.ui.control.Label
        Max_Shear              matlab.ui.control.NumericEditField
        EditField2_3Label_9    matlab.ui.control.Label
        ShearLabel_2           matlab.ui.control.Label
        Theta_P2               matlab.ui.control.NumericEditField
        EditField2_3Label_8    matlab.ui.control.Label
        Theta_P1               matlab.ui.control.NumericEditField
        EditField2_3Label_7    matlab.ui.control.Label
        Sigma_2                matlab.ui.control.NumericEditField
        EditField2_3Label_6    matlab.ui.control.Label
        Sigma_1                matlab.ui.control.NumericEditField
        EditField2_3Label_5    matlab.ui.control.Label
        CalculateButton        matlab.ui.control.StateButton
        PrincipalsLabel        matlab.ui.control.Label
        Rot_Angle              matlab.ui.control.NumericEditField
        EditField2Label        matlab.ui.control.Label
        Shear_1                matlab.ui.control.NumericEditField
        EditField2_3Label_4    matlab.ui.control.Label
        Shear                  matlab.ui.control.NumericEditField
        ShearLabel             matlab.ui.control.Label
        Sigma_y1               matlab.ui.control.NumericEditField
        EditField2_3Label_3    matlab.ui.control.Label
        Sigma_y                matlab.ui.control.NumericEditField
        EditField2_2Label      matlab.ui.control.Label
        Sigma_x1               matlab.ui.control.NumericEditField
        EditField2_3Label_2    matlab.ui.control.Label
        Sigma_x                matlab.ui.control.NumericEditField
        EditField2_3Label      matlab.ui.control.Label
        RotationLabel          matlab.ui.control.Label
        OutputParametersLabel  matlab.ui.control.Label
        InputParametersLabel   matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
           
            % Store Inputs
            sigma_x = app.Sigma_x.Value;
            sigma_y = app.Sigma_y.Value;
            t_xy = app.Shear.Value;
            angle = app.Rot_Angle.Value;

            % Apply Equations

            angle = angle *pi/180; %from degree to Rad
            avg = (sigma_x + sigma_y)/2;
            diff_avg = (sigma_x - sigma_y)/2;
            sigma_x1 = avg + diff_avg*cos(2*angle) + t_xy*sin(2*angle);
            %sigma_y1 = avg - diff_avg*cos(2*angle) - t_xy*sin(2*angle);
            sigma_y1 = sigma_y + sigma_x - sigma_x1;
            t_x1y1 =  -1*diff_avg*sin(2*angle) + t_xy*cos(2*angle);
            theta_p1 = (1/2)*atan((t_xy)/(diff_avg));
            theta_p1 = 180*theta_p1/pi;%from Rad to degree
            theta_p2 = theta_p1 + 90;
            theta_s1  = theta_p1 + 45;
            theta_s2  = theta_s1 - 90;
            R = sqrt((diff_avg)^2 + (t_xy)^2);
            sigma_1 = avg + R;
            sigma_2 = avg - R;
            if (t_xy ==0 && sigma_y == sigma_x) 
                uialert(app.UIFigure, 'Wrong Inputs No valid angle solutions','success');
                return;
            end

            % Display Results
            app.Sigma_x1.Value  = sigma_x1;
            app.Sigma_y1.Value  = sigma_y1;
            app.Shear_1.Value   = t_x1y1;
            app.Sigma_1.Value   = sigma_1;
            app.Sigma_2.Value   = sigma_2;
            app.Theta_P1.Value  = theta_p1;
            app.Theta_P2.Value  = theta_p2;
            app.Max_Shear.Value = R;
            app.Theta_S.Value   = theta_s1;
            app.Theta_S_2.Value   = theta_s2;
            % Figure

            theta = linspace(0, 2*pi, 1000);
            x = R * cos(theta) + avg;
            y = R * sin(theta);
            set(app.UIAxes, 'YDir', 'reverse');
            

            % check specical figure Case

            if (sigma_x == sigma_y)
                X = sigma_x*ones(1,2);
                Y = linspace(-t_xy,t_xy,2);
            else
                X = linspace(sigma_x,sigma_y,2);
                Y = X*(t_xy/diff_avg) - (sigma_x * t_xy)/(diff_avg) + t_xy;
            end
            
            diff_avg_2 = (sigma_x1 - sigma_y1)/2;
            if (sigma_x1 == sigma_y1)
                D1 = sigma_x1*ones(1,2);
                D2 = linspace(-t_x1y1,t_x1y1,2);
            else
                D1 = linspace(sigma_x1,sigma_y1,2);
                D2 = D1*(t_x1y1/diff_avg_2) - (sigma_x1 * t_x1y1)/(diff_avg_2) + t_x1y1;
            end
            
            
            plot(x, y, 'b', 'Parent', app.UIAxes); % Mohr's circle
            hold(app.UIAxes, 'on');
            h2 = plot(X, Y, 'r', 'Parent', app.UIAxes); % Line
            h3 = plot(D1, D2, 'Color', [1, 0.5, 0], 'Parent', app.UIAxes); % Line
            legend(app.UIAxes , [h2,h3] , {'Perpendicular characteristic','Rotation characteristic'})
            hold(app.UIAxes, 'off');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Mohr''s Circle')
            xlabel(app.UIAxes, 'σ_x1')
            ylabel(app.UIAxes, 'Shear')
            zlabel(app.UIAxes, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.InputParametersLabel = uilabel(app.UIFigure);
            app.InputParametersLabel.Text = 'Input Parameters';
            app.OutputParametersLabel = uilabel(app.UIFigure);
            app.OutputParametersLabel.Text = 'Output Parameters';
            app.RotationLabel = uilabel(app.UIFigure);
            app.RotationLabel.Text = 'Rotation';
            app.EditField2_3Label = uilabel(app.UIFigure);
            app.EditField2_3Label.Text = 'Sigma_x  ';
            app.Sigma_x = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3Label_2 = uilabel(app.UIFigure);
            app.EditField2_3Label_2.Text = 'Sigma_x1 ';
            app.Sigma_x1 = uieditfield(app.UIFigure, 'numeric');
            app.Sigma_x1.Editable = 'off';
            app.EditField2_2Label = uilabel(app.UIFigure);
            app.EditField2_2Label.Text = 'Sigma_y  ';
            app.Sigma_y = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3Label_3 = uilabel(app.UIFigure);
            app.EditField2_3Label_3.Text = 'Sigma_y1';
            app.Sigma_y1 = uieditfield(app.UIFigure, 'numeric');
            app.Sigma_y1.Editable = 'off';
            app.ShearLabel = uilabel(app.UIFigure);
            app.ShearLabel.Text = 'Shear      ';
            app.Shear = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_3Label_4 = uilabel(app.UIFigure);
            app.EditField2_3Label_4.Text = 'Shear_1   ';
            app.Shear_1 = uieditfield(app.UIFigure, 'numeric');
            app.Shear_1.Editable = 'off';
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Rot_Angle';
            app.Rot_Angle = uieditfield(app.UIFigure, 'numeric');
            app.PrincipalsLabel = uilabel(app.UIFigure);
            app.PrincipalsLabel.Text = 'Principals';
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.EditField2_3Label_5 = uilabel(app.UIFigure);
            app.EditField2_3Label_5.Text = 'Sigma_1  ';
            app.Sigma_1 = uieditfield(app.UIFigure, 'numeric');
            app.Sigma_1.Editable = 'off';
            app.EditField2_3Label_6 = uilabel(app.UIFigure);
            app.EditField2_3Label_6.Text = 'Sigma_2  ';
            app.Sigma_2 = uieditfield(app.UIFigure, 'numeric');
            app.Sigma_2.Editable = 'off';
            app.EditField2_3Label_7 = uilabel(app.UIFigure);
            app.EditField2_3Label_7.Text = 'Theta_P_1';
            app.Theta_P1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_P1.Editable = 'off';
            app.EditField2_3Label_8 = uilabel(app.UIFigure);
            app.EditField2_3Label_8.Text = 'Theta_P_2';
            app.Theta_P2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_P2.Editable = 'off';
            app.ShearLabel_2 = uilabel(app.UIFigure);
            app.ShearLabel_2.Text = 'Shear';
            app.EditField2_3Label_9 = uilabel(app.UIFigure);
            app.EditField2_3Label_9.Text = 'Max_Shear';
            app.Max_Shear = uieditfield(app.UIFigure, 'numeric');
            app.Max_Shear.Editable = 'off';
            app.EditField2_3Label_10 = uilabel(app.UIFigure);
            app.EditField2_3Label_10.Text = 'Theta_S1  ';
            app.Theta_S = uieditfield(app.UIFigure, 'numeric');
            app.Theta_S.Editable = 'off';
            app.EditField2_3Label_11 = uilabel(app.UIFigure);
            app.EditField2_3Label_11.Text = 'Theta_S2  ';
            app.Theta_S_2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta_S_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = group_2_stress_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end